classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GraficaM                  matlab.ui.control.UIAxes
        GraficaMD                 matlab.ui.control.UIAxes
        BtnGraficar               matlab.ui.control.Button
        GarcaGarcaJosngelLabel    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        BtnBorrar                 matlab.ui.control.Button
        bitsGenerado              matlab.ui.control.Label
        bitsGenerado2             matlab.ui.control.Label
        TrendebitsEditFieldLabel  matlab.ui.control.Label
        TrendebitsEditField       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BtnGraficar
        function BtnGraficarButtonPushed(app, event)
            entrada_tren = app.TrendebitsEditField.Value
            bits = str2num(entrada_tren)
            
            % Manchester
            valTrans = [];
            for i = 1 : length(bits)
                if bits(i) == 0
                    transicion = [1 -1];
                else
                    transicion = [-1 1];
                end
                valTrans = [ valTrans transicion];
            end
            k = 1;
            l = 0.5;
            T = 0 : 0.01 : length(bits);
            for j = 1: length(T)
                y(j) = valTrans(k);
                if T(j) > l
                    k = k + 1;
                    l = l + 0.5;
                end
            end
            plot(app.GraficaM, T, y);
            axis(app.GraficaM, [0 length(bits) -2 2]);
            
            % Manchester Diferencial
            valTrans = []; % Arreglo de los valores de la transición
            if bits(1) == 0  % Verificamos si el primer bit es 0
                transicion = [-1 1]; % Realizamos una transición de -1 a 1
            else
                transicion = [1 -1]; % Si es 1, entonces realizamos una transición de 1 a -1
            end
            valTrans = [ valTrans transicion];
            k = 2;
            for i = 2 : length(bits)
                if bits(i) == 0
                    tran = [valTrans(k - 1) valTrans(k)];
                else
                    tran = [valTrans(k) valTrans(k - 1)];
                end
                k = k + 2;
                valTrans = [valTrans tran];
            end
            
            k = 1;
            l = 0.5;
            T = 0 : 0.01 : length(bits);
            for j = 1: length(T)
                y(j) = valTrans(k);
                if T(j) > l
                    k = k + 1;
                    l = l + 0.5;
                end
            end
            plot(app.GraficaMD, T, y);
            axis(app.GraficaMD, [0 length(bits) -2 2]);
        end

        % Button pushed function: BtnBorrar
        function BtnBorrarButtonPushed(app, event)
            app.TrendebitsEditField.Value = ' ';
            app.GraficaM.clo;
            axis(app.GraficaM, [0 1 0 1]);
            app.GraficaMD.clo;
            axis(app.GraficaMD, [0 1 0 1]);
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraficaM = uiaxes(app.UIFigure);
            title(app.GraficaM, 'Manchester')
            xlabel(app.GraficaM, 'Tiempo')
            ylabel(app.GraficaM, 'Amplitud')
            app.GraficaMD = uiaxes(app.UIFigure);
            title(app.GraficaMD, {'Manchester Diferencial'; ''})
            xlabel(app.GraficaMD, 'Tiempo')
            ylabel(app.GraficaMD, 'Amplitud')
            app.BtnGraficar = uibutton(app.UIFigure, 'push');
            app.BtnGraficar.ButtonPushedFcn = createCallbackFcn(app, @BtnGraficarButtonPushed, true);
            app.BtnGraficar.Text = 'Graficar';
            app.GarcaGarcaJosngelLabel = uilabel(app.UIFigure);
            app.GarcaGarcaJosngelLabel.Text = 'García García José Ángel';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Fundamentos de Telecomunicaciones - Graficador de Modulación Manchester y Manchester Diferencial';
            app.BtnBorrar = uibutton(app.UIFigure, 'push');
            app.BtnBorrar.ButtonPushedFcn = createCallbackFcn(app, @BtnBorrarButtonPushed, true);
            app.BtnBorrar.Text = 'Borrar';
            app.bitsGenerado = uilabel(app.UIFigure);
            app.bitsGenerado.Text = '---';
            app.bitsGenerado2 = uilabel(app.UIFigure);
            app.bitsGenerado2.Text = '---';
            app.TrendebitsEditFieldLabel = uilabel(app.UIFigure);
            app.TrendebitsEditFieldLabel.Text = 'Tren de bits';
            app.TrendebitsEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end